classdef Simple_Robotics1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        OnOFFSwitch       matlab.ui.control.Switch
        OnOFFSwitchLabel  matlab.ui.control.Label
        L1EditField       matlab.ui.control.NumericEditField
        L1EditFieldLabel  matlab.ui.control.Label
        L2EditField       matlab.ui.control.NumericEditField
        L2EditFieldLabel  matlab.ui.control.Label
        SimulationButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        ContextMenu       matlab.ui.container.ContextMenu
        Menu              matlab.ui.container.Menu
        Menu2             matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulationButton
        function SimulationButtonPushed(app, event)
           

   hold(app.UIAxes,'off')
L1 = str2double(app.L1EditField.Value);
L2 = str2double(app.L2EditField.Value);
steps = 2000;
theta1 = linspace(0,90,steps);  theta1 = theta1(:);   % Column vector
theta2 = linspace(10,60,steps); theta2 = theta2(:);   % Column vector
x1 = L1*cosd(theta1);
y1 = L1.*sind(theta1);
x2 = x1 + L2.*cosd(theta1 + theta2);
y2 = y1 + L2.*sind(theta1 + theta2);
L1plot = plot(app.UIAxes, [0,x1(1)],[0,y1(1)],"Color",'r','LineWidth', 4);
hold(app.UIAxes,'on')

L2plot = plot(app.UIAxes,[x1(1),x2(1)],[y1(1),y2(1)],"Color",'b','LineWidth', 4);
maxIndex = min([length(x1), length(y1)]);

xlim(app.UIAxes, sort([-L1*2, L1*2]))
ylim(app.UIAxes, sort([-L1*2, L1*2]))

plot(app.UIAxes,x2,y2);
last_index = 1;
while(app.Switch.Value == "On")
for i = last_index:10:maxIndex

delete(L1plot);
delete(L2plot);
L1plot = plot(app.UIAxes, [0,x1(i)],[0,y1(i)],"Color",'r','LineWidth', 4);
L2plot = plot(app.UIAxes,[x1(i),x2(i)],[y1(i),y2(i)],"Color",'b','LineWidth', 4);
grid on
drawnow
pause(0.002);
    if (app.Switch.Value == "Off")
        last_index = i;

          break;
    end
end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationButton = uibutton(app.UIFigure, 'push');
            app.SimulationButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationButtonPushed, true);
            app.SimulationButton.Text = 'Simulation';
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.OnOFFSwitchLabel = uilabel(app.UIFigure);
            app.OnOFFSwitchLabel.Text = 'On/OFF';
            app.OnOFFSwitch = uiswitch(app.UIFigure, 'slider');
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.SimulationButton.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simple_Robotics1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end